classdef CRY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FarmersGrainMarketLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        SpectrogramskinsButtonGroup  matlab.ui.container.ButtonGroup
        FestiveButton                matlab.ui.control.RadioButton
        TropicButton                 matlab.ui.control.RadioButton
        SunsetButton                 matlab.ui.control.RadioButton
        MonoButton                   matlab.ui.control.RadioButton
        JetButton                    matlab.ui.control.RadioButton
        AuburnButton                 matlab.ui.control.RadioButton
        ShrekButton                  matlab.ui.control.RadioButton
        TabGroup                     matlab.ui.container.TabGroup
        PitchandSpeedTab             matlab.ui.container.Tab
        SpeedKnob                    matlab.ui.control.Knob
        PitchShiftCheckBox           matlab.ui.control.CheckBox
        FlipBackwardsCheckBox        matlab.ui.control.CheckBox
        Label                        matlab.ui.control.Label
        PitchKnob                    matlab.ui.control.Knob
        SpeedshiftCheckBox           matlab.ui.control.CheckBox
        ConvolutionTab               matlab.ui.container.Tab
        FourierTransformButtonGroup  matlab.ui.container.ButtonGroup
        ShortButton                  matlab.ui.control.RadioButton
        FastButton                   matlab.ui.control.RadioButton
        FilestateButtonGroup         matlab.ui.container.ButtonGroup
        ModifiedButton               matlab.ui.control.RadioButton
        OriginalButton               matlab.ui.control.RadioButton
        GrainfileLabel               matlab.ui.control.Label
        InputfileLabel               matlab.ui.control.Label
        ReduceNoiseCheckBox          matlab.ui.control.CheckBox
        EnvelopingTab                matlab.ui.container.Tab
        EnvelopingCheckBox           matlab.ui.control.CheckBox
        DropKnobLabel                matlab.ui.control.Label
        DropKnob                     matlab.ui.control.Knob
        AttackKnobLabel              matlab.ui.control.Label
        AttackKnob                   matlab.ui.control.Knob
        SustainKnobLabel             matlab.ui.control.Label
        SustainKnob                  matlab.ui.control.Knob
        ReleaseKnobLabel             matlab.ui.control.Label
        ReleaseKnob                  matlab.ui.control.Knob
        NoteifthesumoftheADSRisover100itwontworkLabel  matlab.ui.control.Label
        PlaybackButtonGroup          matlab.ui.container.ButtonGroup
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        FilesIOstreamButtonGroup     matlab.ui.container.ButtonGroup
        LoadAudioButton              matlab.ui.control.Button
        SaveAudioButton              matlab.ui.control.Button
        FunctionalityButtonGroup     matlab.ui.container.ButtonGroup
        GranularConvButton           matlab.ui.control.Button
        DrawButton                   matlab.ui.control.StateButton
        RichinfibrelowinpriceLabel   matlab.ui.control.Label
    end

   
    %C1722514
    
    properties (Access = public)
        Property % Description
        filenamepath  %the file we  l o a d
        y             %wave of file
        Fs            %frequency of file
    end
    
    %C1722514
    
    methods (Access = private)
        
        function SummonSpeccy(app)
            n=512;
            nhop = n/4;
            Y = stft(app.y,n,n,nhop);
            specy = abs(Y)/n;
            imshow((flipud(255*specy)),'parent', app.UIAxes);
            if app.FestiveButton.Value==1
                colormap(app.UIAxes, hsv);
            else if app.TropicButton.Value==1
                    colormap(app.UIAxes, summer);
                else if app.SunsetButton.Value==1
                        colormap(app.UIAxes, autumn);
                    else if app.JetButton.Value==1
                            colormap(app.UIAxes, jet);
                        else if app.AuburnButton.Value==1
                                colormap(app.UIAxes, flag);
                            else if app.ShrekButton.Value==1
                                    colormap(app.UIAxes, colorcube);
                                end
                            end
                        end
                    end
                end
            end
        end
        %Extend a grain file to the size of the file we want to convolve with.
        function finale=MakeEmGrow(app, small, big)
            refSmall=small;
            repeats=floor(length(big)/length(small));
            for j=1:repeats
                refSmall=[refSmall;small];
            end
            if length(big)>length(refSmall)
                refSmall=[refSmall;small(1:length(big)-length(small))];
            end
            finale=refSmall;
        end
        %Fast fourier transform and dot product for the convolution
        function product=fastFourier(app,vec1, vec2)
            X=fft(vec2);
            Y=fft(vec1);
            finalVec=Y.*X;
            product = ifft(finalVec);
        end
        %Enables freehand drawing on spectrogram
        function drawPls(app)
            n=512;
            nhop=n/4;
            Y=stft(app.y,n,n,nhop);
            imshow(flipud(Y));
            if app.FestiveButton.Value==1
                colormap(hsv);
            else if app.TropicButton.Value==1
                    colormap(summer);
                else if app.SunsetButton.Value==1
                        colormap(autumn);
                    else if app.JetButton.Value==1
                            colormap(jet);
                        else if app.AuburnButton.Value==1
                                colormap(flag);
                            else if app.ShrekButton.Value==1
                                    colormap(colorcube);
                                end
                            end
                        end
                    end
                end
            end
            h=imfreehand;
            mask=h.createMask();
            close();
            Ymod=flipud(1-mask).*Y;
            ymod=istft(Ymod,n,n,nhop);
            app.y=real(ymod);
        end
        
        function final=MakeEmShrink(app, big, small)
            final=big(1:length(small));
        end
        %Code that performs enveloping for attack, delay, sustain and release
        %Adapted from MathWorks, created by Wayne King 22nd of September 2011
        % 
        %Courtesy of https://uk.mathworks.com/matlabcentral/answers/16487-query-regarding-linspace?fbclid=IwAR1lQDPlOTIGKCQz2Y-PsETpMgid8IHsQiulrLL5Pxq-yeKqaAWUAxP0Uu8
        function fin=Enveloping(app, wave, att, drop, delay, rel)
            if att+drop+delay+rel<1
                A = linspace(0, .9, (length(wave)*att)); %rise of signal
                D = linspace(.9, 0.7,(length(wave)*drop)); %drop of signal
                S = linspace(0.7, 0.7,(length(wave)*delay)); %delay signal, sustain
                R = linspace(0.7, 0,(length(wave)*rel)); %drop of signal release
                ADSR = [A D S R] ;
                x = zeros(size(wave)); %Allocate memory
                x(1:length(ADSR)) = ADSR;
                tone=wave.* x;
                fin=tone;
            else
                f=warndlg("Adjust values sum to below 100, then try again.");
                fin=wave;
            end
        end
        
        function fin = ShortFourier(app, vec1, vec2)
            n=1024;
            X=stft(vec1,n,n,n/4);
            if app.ReduceNoiseCheckBox.Value==1
                X=medfilt2(real(X));
            end
            Y=stft(vec2,n,n,n/4);
            finalVec=Y.*X;
            fin = istft(finalVec,n,n,n/4);
        end
        
        
        function GranularConvolution(app, temp, grains, grainsFs)
            Ly=length(grains);
            temp=zeros(Ly,1); %output signal
            timex = Ly/grainsFs;
            % Constants
            nEv=4000; maxL=app.Fs*0.02;  minL=app.Fs*0.01;  Lw=app.Fs*0.01;
            % Initializations
            L = round((maxL-minL)*rand(nEv,1))+minL;   %grain length
            initIn = ceil((Ly-maxL)*rand(nEv,1));      %init grain
            initOut= ceil((Ly-maxL)*rand(nEv,1));      %init out grain
            a = rand(nEv,1);                           %ampl. grain
            endOut=initOut+L-1;
            % Synthesis
            for k=1:nEv,
                grain=grainLn(grains,initIn(k),L(k),Lw);
                temp(initOut(k):endOut(k))=temp(initOut(k):endOut(k))+ grain;
            end
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            
            [filename,path] = uigetfile({'*.wav;*.waV;','Wav Files'; ...
                '*.*', 'All files (*.*)'}, ...
                'Select a sound file');
            if isequal(filename,0) | isequal(path,0)
                f=warndlg('No file selected, please retry.', 'File absent');
               	return;
            else
                app.filenamepath = [path filename];
                app.InputfileLabel.Text=char(filename);
                [app.y,app.Fs] = audioread(app.filenamepath);
                SummonSpeccy(app);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.SpeedshiftCheckBox.Value==1
                yslow =pvoc(app.y,app.SpeedKnob.Value,1024);
            end
            if app.PitchShiftCheckBox.Value==1
                    [num, den]=rat(app.PitchKnob.Value);
                    ypvoc =pvoc(yslow, app.PitchKnob.Value);
                    ypitch = resample(ypvoc,num,den);
                    yfinal=ypitch;
            end
            if app.FlipBackwardsCheckBox.Value==1
                app.y=flip(app.y);
            end
            if(app.EnvelopingCheckBox.Value==1)
                app.y=Enveloping(app, app.y, app.AttackKnob.Value, app.DropKnob.Value, app.SustainKnob.Value, app.ReleaseKnob.Value);
            end
            %SummonSpeccy(app);
            % Resynthesis
            clear sound;
            sound(app.y,app.Fs);
        end

        % Value changing function: SpeedKnob
        function SpeedKnobValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Value changed function: DrawButton
        function DrawButtonValueChanged(app, event)
            drawPls(app);
            SummonSpeccy(app);
        end

        % Button pushed function: GranularConvButton
        function GranularConvButtonPushed(app, event)
            clear sound;
            [filename,path] = uigetfile({'*.wav;*.waV;','Wav Files'; ...
                '*.*', 'All files (*.*)'}, ...
                'Select a sound file');
            if isequal(filename,0) | isequal(path,0)
                if app.OriginalButton.Value==1
                    [app.y, app.Fs]= audioread(app.filenamepath);
                end
                app.GrainfileLabel.Text=char(app.InputfileLabel.Text);
                temp=app.y(:,1);
                grains=temp;
                GranularConvolution(app, temp, grains, app.Fs);
                if(app.ShortButton.Value==1)
                    envtemp= ShortFourier(app, temp, grains);
                else
                    envtemp = fastFourier(app, temp, grains);
                end
                if(app.EnvelopingCheckBox.Value==1)
                    app.y=Enveloping(app, envtemp, app.AttackKnob.Value, app.DropKnob.Value, app.SustainKnob.Value, app.ReleaseKnob.Value);
                else
                    app.y=envtemp;
                end
                SummonSpeccy(app);
                sound(app.y,app.Fs);
            else
                if app.OriginalButton.Value==1
                    [app.y, app.Fs]= audioread(app.filenamepath);
                end
                filenamepath2 = [path filename];
                [grains,grainsFs] = audioread(filenamepath2);
                app.GrainfileLabel.Text=char(filename);
                temp=app.y(:,1);
                grains=grains(:,1);
                if length(temp)<length(grains)
                    grains=MakeEmShrink(app, grains, temp);
                else 
                    if length(grains)<length(temp)
                        grains=MakeEmGrow(app, temp, grains);
                    end
                end
                GranularConvolution(app, temp, grains, grainsFs);
                if(app.ShortButton.Value==1)
                    envtemp= ShortFourier(app, temp, grains);
                else
                    envtemp = fastFourier(app, temp, grains);
                end
                if(app.EnvelopingCheckBox.Value==1)
                    app.y=Enveloping(app, envtemp, app.AttackKnob.Value, app.DropKnob.Value, app.SustainKnob.Value, app.ReleaseKnob.Value);
                else
                    app.y=envtemp;
                end
                SummonSpeccy(app);
                sound(app.y,app.Fs);
            end
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            [nfname,npath]=uiputfile('.wav','Save sound','new_sound.wav');
            if isequal(nfname,0) || isequal(npath,0)
                f = warndlg('Please input filename.','Warning');
                return
            else
                nwavfile=fullfile(npath, nfname);
                audiowrite(nwavfile, app.y,app.Fs);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FarmersGrainMarketLabel = uilabel(app.UIFigure);
            app.FarmersGrainMarketLabel.Text = 'Farmer''s Grain Market';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Frequency')
            app.SpectrogramskinsButtonGroup = uibuttongroup(app.UIFigure);
            app.SpectrogramskinsButtonGroup.Title = 'Spectrogram skins';
            app.FestiveButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.FestiveButton.Text = 'Festive';
            app.FestiveButton.Value = true;
            app.TropicButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.TropicButton.Text = 'Tropic';
            app.SunsetButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.SunsetButton.Text = 'Sunset';
            app.MonoButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.MonoButton.Text = 'Mono';
            app.JetButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.JetButton.Text = 'Jet';
            app.AuburnButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.AuburnButton.Text = 'Auburn';
            app.ShrekButton = uiradiobutton(app.SpectrogramskinsButtonGroup);
            app.ShrekButton.Text = 'Shrek';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PitchandSpeedTab = uitab(app.TabGroup);
            app.PitchandSpeedTab.Title = 'Pitch and Speed';
            app.SpeedKnob = uiknob(app.PitchandSpeedTab, 'continuous');
            app.SpeedKnob.Limits = [0.01 2];
            app.SpeedKnob.MajorTicks = [0.01 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.SpeedKnob.ValueChangingFcn = createCallbackFcn(app, @SpeedKnobValueChanging, true);
            app.SpeedKnob.Value = 1;
            app.PitchShiftCheckBox = uicheckbox(app.PitchandSpeedTab);
            app.PitchShiftCheckBox.Text = 'Pitch Shift';
            app.FlipBackwardsCheckBox = uicheckbox(app.PitchandSpeedTab);
            app.FlipBackwardsCheckBox.Text = 'Flip Backwards';
            app.Label = uilabel(app.PitchandSpeedTab);
            app.Label.Text = '';
            app.PitchKnob = uiknob(app.PitchandSpeedTab, 'continuous');
            app.PitchKnob.Limits = [0.1 3];
            app.PitchKnob.MajorTicks = [0.1 0.5 0.9 1.3 1.7 2.1 2.5 3];
            app.PitchKnob.Value = 1;
            app.SpeedshiftCheckBox = uicheckbox(app.PitchandSpeedTab);
            app.SpeedshiftCheckBox.Text = 'Speed shift';
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.FourierTransformButtonGroup = uibuttongroup(app.ConvolutionTab);
            app.FourierTransformButtonGroup.Title = 'Fourier Transform';
            app.ShortButton = uiradiobutton(app.FourierTransformButtonGroup);
            app.ShortButton.Text = 'Short';
            app.ShortButton.Value = true;
            app.FastButton = uiradiobutton(app.FourierTransformButtonGroup);
            app.FastButton.Text = 'Fast';
            app.FilestateButtonGroup = uibuttongroup(app.ConvolutionTab);
            app.FilestateButtonGroup.Title = 'File state';
            app.ModifiedButton = uiradiobutton(app.FilestateButtonGroup);
            app.ModifiedButton.Text = 'Modified';
            app.ModifiedButton.Value = true;
            app.OriginalButton = uiradiobutton(app.FilestateButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.GrainfileLabel = uilabel(app.ConvolutionTab);
            app.GrainfileLabel.Text = 'Grain file.';
            app.InputfileLabel = uilabel(app.ConvolutionTab);
            app.InputfileLabel.Text = 'Input file.';
            app.ReduceNoiseCheckBox = uicheckbox(app.ConvolutionTab);
            app.ReduceNoiseCheckBox.Text = 'Reduce Noise';
            app.ReduceNoiseCheckBox.Value = true;
            app.EnvelopingTab = uitab(app.TabGroup);
            app.EnvelopingTab.Title = 'Enveloping';
            app.EnvelopingCheckBox = uicheckbox(app.EnvelopingTab);
            app.EnvelopingCheckBox.Text = 'Enveloping';
            app.DropKnobLabel = uilabel(app.EnvelopingTab);
            app.DropKnobLabel.Text = 'Drop';
            app.DropKnob = uiknob(app.EnvelopingTab, 'continuous');
            app.DropKnob.Limits = [0 1];
            app.DropKnob.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.AttackKnobLabel = uilabel(app.EnvelopingTab);
            app.AttackKnobLabel.Text = 'Attack';
            app.AttackKnob = uiknob(app.EnvelopingTab, 'continuous');
            app.AttackKnob.Limits = [0 1];
            app.AttackKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.AttackKnob.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.SustainKnobLabel = uilabel(app.EnvelopingTab);
            app.SustainKnobLabel.Text = 'Sustain';
            app.SustainKnob = uiknob(app.EnvelopingTab, 'continuous');
            app.SustainKnob.Limits = [0 1];
            app.SustainKnob.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.ReleaseKnobLabel = uilabel(app.EnvelopingTab);
            app.ReleaseKnobLabel.Text = 'Release';
            app.ReleaseKnob = uiknob(app.EnvelopingTab, 'continuous');
            app.ReleaseKnob.Limits = [0 1];
            app.ReleaseKnob.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.NoteifthesumoftheADSRisover100itwontworkLabel = uilabel(app.EnvelopingTab);
            app.NoteifthesumoftheADSRisover100itwontworkLabel.Text = 'Note: if the sum of the ADSR is over 100, it won''t work.';
            app.PlaybackButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaybackButtonGroup.Title = 'Playback';
            app.PlayButton = uibutton(app.PlaybackButtonGroup, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlaybackButtonGroup, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FilesIOstreamButtonGroup = uibuttongroup(app.UIFigure);
            app.FilesIOstreamButtonGroup.Title = 'Files I/O stream';
            app.LoadAudioButton = uibutton(app.FilesIOstreamButtonGroup, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.SaveAudioButton = uibutton(app.FilesIOstreamButtonGroup, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            app.FunctionalityButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionalityButtonGroup.Title = 'Functionality';
            app.GranularConvButton = uibutton(app.FunctionalityButtonGroup, 'push');
            app.GranularConvButton.ButtonPushedFcn = createCallbackFcn(app, @GranularConvButtonPushed, true);
            app.GranularConvButton.Text = 'Granular Conv';
            app.DrawButton = uibutton(app.FunctionalityButtonGroup, 'state');
            app.DrawButton.ValueChangedFcn = createCallbackFcn(app, @DrawButtonValueChanged, true);
            app.DrawButton.Text = 'Draw';
            app.RichinfibrelowinpriceLabel = uilabel(app.UIFigure);
            app.RichinfibrelowinpriceLabel.Text = 'Rich in fibre, low in price';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CRY

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end